classdef ARES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout19                    matlab.ui.container.GridLayout
        MainTabs                        matlab.ui.container.TabGroup
        PowerAmplifierTab               matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        InstrumentConnectionsPanel      matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        SpectrumAnalyzerDropDown        matlab.ui.control.DropDown
        SpectrumAnalyzerDropDownLabel   matlab.ui.control.Label
        SignalGeneratorDropDown         matlab.ui.control.DropDown
        SignalGeneratorDropDownLabel    matlab.ui.control.Label
        PowerSupplyDropDown             matlab.ui.control.DropDown
        PowerSupplyDropDownLabel        matlab.ui.control.Label
        PAConnectButton                 matlab.ui.control.Button
        PAScanButton                    matlab.ui.control.Button
        PASettingsPanel                 matlab.ui.container.TabGroup
        PowerSupplyTab                  matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        SupplyDropDown                  matlab.ui.control.DropDown
        SupplyDropDownLabel             matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDownLabel               matlab.ui.control.Label
        SupplyVoltageStep               matlab.ui.control.NumericEditField
        SupplyVoltageStepVEditField     matlab.ui.control.Label
        SupplyVoltageStop               matlab.ui.control.NumericEditField
        SupplyVoltageStopVEditField     matlab.ui.control.Label
        SupplyVoltage                   matlab.ui.control.NumericEditField
        SupplyVoltageVEditField         matlab.ui.control.Label
        SignalGeneratorTab              matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        PowerStep                       matlab.ui.control.NumericEditField
        EndPower                        matlab.ui.control.NumericEditField
        StartPower                      matlab.ui.control.NumericEditField
        FrequencyStep                   matlab.ui.control.NumericEditField
        EndFrequency                    matlab.ui.control.NumericEditField
        StartFrequency                  matlab.ui.control.NumericEditField
        PowerStepdBmEditField           matlab.ui.control.Label
        EndPowerdBmEditFieldLabel       matlab.ui.control.Label
        StartPowerdBmEditFieldLabel     matlab.ui.control.Label
        FrequencyStepMHzLabel           matlab.ui.control.Label
        StartFrequencyMHzLabel          matlab.ui.control.Label
        EndFrequencyMHzLabel            matlab.ui.control.Label
        SpectrumAnalyzerTab             matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        CenterFrequency                 matlab.ui.control.NumericEditField
        CenterFrequencyMHzEditField     matlab.ui.control.Label
        Span                            matlab.ui.control.NumericEditField
        SweepPoints                     matlab.ui.control.NumericEditField
        ReferenceLevel                  matlab.ui.control.NumericEditField
        SpanMHzEditField                matlab.ui.control.Label
        SweepPointsEditField            matlab.ui.control.Label
        ReferenceLeveldBEditField       matlab.ui.control.Label
        MeasurementPanel                matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        MeasurementDropDown             matlab.ui.control.DropDown
        MeasurementDropDownLabel        matlab.ui.control.Label
        PlotPAData                      matlab.ui.control.Button
        SavePAData                      matlab.ui.control.Button
        LoadPAData                      matlab.ui.control.Button
        StartPAMeasurement              matlab.ui.control.Button
        ResultsTabs                     matlab.ui.container.TabGroup
        SingleTab                       matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        SingleGain                      matlab.ui.control.UIAxes
        SweepTab                        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        SweepPeakDEPAE                  matlab.ui.control.UIAxes
        SweepCompressionPoints          matlab.ui.control.UIAxes
        SweepPeakGain                   matlab.ui.control.UIAxes
        SweepGain                       matlab.ui.control.UIAxes
        AntennaTab                      matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        InstrumentConnectionsPanel_2    matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        EMCenterDropDown                matlab.ui.control.DropDown
        EMCenterDropDownLabel           matlab.ui.control.Label
        VNADropDown                     matlab.ui.control.DropDown
        VNADropDownLabel                matlab.ui.control.Label
        AntennaConnectButton            matlab.ui.control.Button
        AntennaScanButton               matlab.ui.control.Button
        AntennaSettings                 matlab.ui.container.TabGroup
        VNATab                          matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        BoresightGainFileField          matlab.ui.control.EditField
        PlotReferenceGainButton         matlab.ui.control.Button
        BrowseFilesButton               matlab.ui.control.Button
        VNAMeasurement                  matlab.ui.control.DropDown
        MeasurementDropDown_2Label      matlab.ui.control.Label
        VNAEndFrequency                 matlab.ui.control.NumericEditField
        EndFrequencyMHzLabel_6          matlab.ui.control.Label
        VNAStartFrequency               matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_15    matlab.ui.control.Label
        Table                           matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        TableSpeed                      matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_14    matlab.ui.control.Label
        TableEndAngle                   matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_13    matlab.ui.control.Label
        TableStepAngle                  matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_12    matlab.ui.control.Label
        TableStartAngle                 matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_11    matlab.ui.control.Label
        TowerTab                        matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        TowerSpeed                      matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_19    matlab.ui.control.Label
        TowerEndAngle                   matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_18    matlab.ui.control.Label
        TowerStepAngle                  matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_17    matlab.ui.control.Label
        TowerStartAngle                 matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_16    matlab.ui.control.Label
        LinearSliderTab                 matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        AntennaSize                     matlab.ui.control.NumericEditField
        AntennaOffsetcmEditFieldLabel   matlab.ui.control.Label
        SpeedPresetDropDown             matlab.ui.control.DropDown
        SpeedPresetDropDownLabel        matlab.ui.control.Label
        AntennaCurrentSpacing           matlab.ui.control.NumericEditField
        CurrentSpacingmEditFieldLabel   matlab.ui.control.Label
        LinearSliderCurrentPosition     matlab.ui.control.NumericEditField
        CurrentPositioncmEditFieldLabel  matlab.ui.control.Label
        LinearSliderNewPosition         matlab.ui.control.NumericEditField
        MovetoPositionButton            matlab.ui.control.Button
        TestSettingsPanel               matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        PlotResultsButton               matlab.ui.control.Button
        LoadResultsButton               matlab.ui.control.Button
        StartTestButton                 matlab.ui.control.Button
        SaveTestButton                  matlab.ui.control.Button
        PlotSettingsPanel               matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        PlottingAngleDropDown           matlab.ui.control.DropDown
        PlottingAngleDropDownLabel      matlab.ui.control.Label
        PlottingFrequencyDropDown       matlab.ui.control.DropDown
        PlottingFrequencyDropDownLabel  matlab.ui.control.Label
        AntennaPattern                  matlab.ui.container.TabGroup
        MeasurementAntennaGainTab       matlab.ui.container.Tab
        GridLayout3_2                   matlab.ui.container.GridLayout
        GainvsFrequencyBoresightGain    matlab.ui.control.UIAxes
        ReturnLossBoresightGain         matlab.ui.control.UIAxes
        DPatternMeasurementTab          matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        GainvsAngle2D                   matlab.ui.control.UIAxes
        GainvsFrequency2D               matlab.ui.control.UIAxes
        ReturnLoss2D                    matlab.ui.control.UIAxes
        InfoTab                         matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        Hyperlink2_3                    matlab.ui.control.Hyperlink
        Hyperlink2_2                    matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
        AuthorJoseAbrahanBolaosVargasMentorAlexDSantiagoVargasLabel  matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        V01BETARelaseLabel              matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        %% Instrument Objects
        PowerSupply            % Power Supply 
        SignalGenerator        % Signal Generator
        SpectrumAnalyzer       % Spectrum Analyzer
        VNA                    % Vector Network Analyzer
        EMCenter               % Anechoic Chamber Control  
        LinearSlider           % Linear Slider

        %% Power Amplifier Properties
        mType                  % PA measurement type: single or sweep frequencies
        inputFrequencySingle   % Frequency scalar for single measurement (Hz)
        inputPdBmSingle        % RF Input Power for single measurement (dBm)
        inputFrequencySweep    % Frequency array for sweep measurement (Hz)
        inputPdBmSweep         % RF Input Power for sweep ,easurement (dBm)
        output_PdBm            % RF Output Power (dBm)
        DCPower                % DC Bias Power (W)
        PAGain                 % Power Amplifier - Gain (dB)
        DE                     % Drain Efficiency (%)
        PAE                    % Power-Added Efficiency (%)

        %% Antenna Properties
        azimuthAngles          % Azimuth Angles for Table Measurement (deg)
        elevationAngles        % Elevation Angles for Tower Measurement (deg)
        sweepFrequencies       % VNA sweep frequencies (Hz)
        sParameters            % Recovered S-Parameters from VNA (dB)
        antennaGain            % Measured antenna gain (dBi)

        BoresightGainFile

        offsetSpacing = 0.8062; % Spacing between antennas (m)
        linearSpacing          % Spacing added by linear slider (m)
        setupSpacing           % Total spacing between antennas accounting for LS and offset (m)

        selectedAngle          % Selected angle to plot versus frequency spectrum
        selectedFrequency      % Selected frequency to plot versus angle spectrum

        angleRange
        frequencyRange
        RadiationPlot2D
    end
   
    
    methods (Access = private)

        function getInstrumentResources(app, instrumentNames)
            % This function gets a list of connected VISA resources, and
            % populates them to the dropdown menus. After the user presses
            % the scan button on the GUI.
            app.AntennaScanButton.Enable = 'off';
            resourceList = visadevlist("Timeout", 40);
            allResources = resourceList.ResourceName';
            
            % Clear the items of each instrument dropdown
            for i = 1:numel(instrumentNames)
                dropdownFieldName = strcat(instrumentNames{i}, 'DropDown');
                if isprop(app, dropdownFieldName) 
                    app.(dropdownFieldName).Items = {};
                end
            end
     
            dropdownItems = [allResources, "None"];

            for i = 1:numel(allResources)
                if i > numel(instrumentNames)
                    break
                else
                    dropdownFieldName = strcat(instrumentNames{i}, 'DropDown');
                    app.(dropdownFieldName).Items = dropdownItems;
                end
            end
            app.AntennaScanButton.Enable = 'on';
        end
        
        function connectInstrument(app, instrumentNames, instrumentResources)
            % This function checks if the connection to the instrument is
            % valid, and connects the specified address of the instrument.   
            for i = 1:numel(instrumentNames)
                instrumentName = instrumentNames{i};
                instrumentResource = instrumentResources{i};
                dropdownFieldName = strcat(instrumentName, 'DropDown');
                
                % Check if the dropdown is enabled, indicating no existing connection
                if strcmp(app.(dropdownFieldName).Enable, 'on')
                    if ~isempty(instrumentResource)
                        try
                            app.(instrumentName) = visadev(instrumentResource);
                            if isvalid(app.(instrumentName))
                                app.(instrumentName).ByteOrder = 'little-endian';
                                app.(dropdownFieldName).Enable = 'off';
                            else
                                error('Invalid VISA object');
                            end
                        catch ME
                            uialert(app.UIFigure, sprintf('Failed to connect to %s: %s.', instrumentResource, ME.message), 'Error', 'Icon', 'warning');
                        end
                    end
                else
                    continue
                end
            end
        end
    
        function runPAMeasurement(app, measurementType)
            % This function runs either the sweep frequency measurement or the single
            % frequency measurement based on the measurementType parameter.
            try
                supplyVoltage = app.SupplyVoltage.Value;
                if supplyVoltage > 25 || supplyVoltage < -25
                    error('Voltage level outside of power supply range');
                end
                writeline(app.PowerSupply, '*RST');
                writeline(app.PowerSupply, ':SYSTem:REMote');
                writeline(app.PowerSupply, sprintf(':APPLy %s,%g,%s', 'P25V', supplyVoltage, 'MAXimum'));
            catch errorObj
                errordlg(errorObj.message, 'Power Supply Error');
                return;
            end
        
            if strcmp(measurementType, 'Sweep')
                app.inputFrequencySweep = app.StartFrequency.Value*1E6:app.FrequencyStep.Value*1E6:app.EndFrequency.Value*1E6;
                app.inputPdBmSweep = app.StartPower.Value:app.PowerStep.Value:app.EndPower.Value;
                app.output_PdBm = zeros(1, length(app.inputPdBmSweep));
                app.DCPower = zeros(1, length(app.inputPdBmSweep));
            else
                app.inputFrequencySingle = app.StartFrequency.Value * 1E6;
                app.inputPdBmSingle = app.StartPower.Value:app.PowerStep.Value:app.EndPower.Value;
                app.output_PdBm = zeros(1, length(app.inputPdBmSingle));
                app.DCPower = zeros(1, length(app.inputPdBmSingle));
            end

            sweepPoints = app.SweepPointsSingle.Value;
            refLevel = app.ReferenceLevelSingle.Value;
        
            % Reset the signal analyzer
            writeline(app.SpectrumAnalyzer, '*RST');
            writeline(app.SpectrumAnalyzer, sprintf(':SENSe:SWEep:POINts %d', sweepPoints));
            writeline(app.SpectrumAnalyzer, sprintf(':DISPlay:WINDow:TRACe:Y:SCALe:RLEVel %g', refLevel));
            writeline(app.SpectrumAnalyzer, sprintf(':FORMat:TRACe:DATA %s,%d', 'REAL', 64));
            writeline(app.SpectrumAnalyzer, sprintf(':FORMat:BORDer %s', 'SWAPped'));
        
            % Reset the signal generator
            writeline(app.SignalGenerator, '*RST');
            writeline(app.SignalGenerator, sprintf(':OUTPut:STATe %d', 1));
        
            % Turn on the power supply
            writeline(app.PowerSupply, sprintf(':OUTPut:STATe %d', 1));
        
            if strcmp(measurementType, 'Sweep')
                for i = 1:length(app.inputFrequencySweep)
                    center_Freq = app.inputFrequencySweep(i);
                    span = app.inputFrequencySweep(i) / 2;
                    writeline(app.SignalGenerator, sprintf(':SOURce:FREQuency:CW %d', app.inputFrequencySweep(i)));
                    writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:CENTer %g', center_Freq));
                    writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:SPAN %g', span));
        
                    for j = 1:length(app.inputPdBmSweep)
                        [app.output_PdBm(i, j), app.DCPower(i, j)] = measureRFOutput(app.SignalGenerator, app.SpectrumAnalyzer, app.PowerSupply, app.inputPdBmSweep(j));
                    end
                end
            else
                writeline(app.SignalGenerator, sprintf(':SOURce:FREQuency:CW %d', app.inputFrequencySingle));
                writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:CENTer %g', app.CenterFrequency.Value * 1E6));
                writeline(app.SpectrumAnalyzer, sprintf(':SENSe:FREQuency:SPAN %g', app.Span.Value * 1E6));
        
                for i = 1:length(app.inputPdBmSingle)
                    [app.output_PdBm(i), app.DCPower(i)] = measureRFOutput(app.SignalGenerator, app.SpectrumAnalyzer, app.PowerSupply, app.inputPdBmSingle(i));
                end
            end
        
            % Turn off the power supply
            writeline(app.PowerSupply, sprintf(':OUTPut:STATe %d', 0));
        
            % Turn off the signal generator
            writeline(app.SignalGenerator, sprintf(':SOURce:POWer:LEVel:IMMediate:AMPLitude %s', 'MIN'));
            writeline(app.SignalGenerator, sprintf(':OUTPut:STATe %d', 0));
            writeline(app.SignalGenerator, sprintf(':OUTPut:MODulation:STATe %d', 0));
        
            % Calculate Gain, DE, and PAE
            if strcmp(measurementType, 'Sweep')
                [app.PAGain, app.DE, app.PAE] = measureRFParameters(app.inputPdBmSweep, app.output_PdBm, app.DCPower);
            else
                [app.PAGain, app.DE, app.PAE] = measureRFParameters(app.inputPdBmSingle, app.output_PdBm, app.DCPower);
            end
        end

        function peakValues = measureRFpeaks(app)
            % This function is used with the sweep type measurement, it
            % calculates the peak values of several RF paramters (Gain, DE,
            % PAE, Saturation Power, -1dB and -3dB compression points).

            peak_Gain = zeros(size(app.inputFrequencySweep, 1), 1);
            peak_1dB  = zeros(size(app.inputFrequencySweep, 1), 1);
            peak_3dB  = zeros(size(app.inputFrequencySweep, 1), 1);
        
            % Window size for the moving average
            windowSize = 3; 
            
            for i = 1:size(app.inputFrequencySweep, 1)
                smoothedGain = movmean(app.PAGain(i, :), windowSize);
                peak_Gain(i) = max(smoothedGain);
            
                % Find the -1dB compression point
                idx_1dB = find(smoothedGain <= (peak_Gain(i) - 1), 1, 'first');
                if ~isempty(idx_1dB)
                    peak_1dB(i) = app.output_PdBm(i, idx_1dB);
                else
                    peak_1dB(i) = NaN;
                end
            
                % Find the -3dB compression point
                idx_3dB = find(smoothedGain <= (peak_Gain(i) - 3), 1, 'first');
                if ~isempty(idx_3dB)
                    peak_3dB(i) = app.output_PdBm(i, idx_3dB);
                else
                    peak_3dB(i) = NaN;
                end
            end
        
            saturation_PdBm = max(app.output_PdBm, [], 2);
            peak_DE  = max(app.DE, [], 2);
            peak_PAE = max(app.PAE, [], 2);
        
            peakValues = {saturation_PdBm, peak_Gain, peak_DE, peak_PAE, peak_1dB', peak_3dB'};
        end

        function plotPASingleMeasurement(app)
            % This function plots the data from the single measurement
            % type. The first plot shows input power vs. output power. The
            % second plot shows the DE, PAE, gain, and compression points
            % if found.
            cla(app.SingleInVersusOut);
            cla(app.SingleGain);

            % Window size for the moving average
            % REMOVE ONCE POWER SUPPLY IS FIXED
            windowSize = 3; 
            smoothedGain = movmean(app.PAGain, windowSize);
            maxGain = max(smoothedGain);
            idx_1dB = find(smoothedGain <= (maxGain - 1), 1, 'first');
            idx_3dB = find(smoothedGain <= (maxGain - 3), 1, 'first'); 
            [saturation_power, saturation_idx] = max(app.output_PdBm);

            % Plot Input Power vs Output Power
            plot(app.SingleInVersusOut, app.inputPdBmSingle, app.output_PdBm, 'blue');
            xlabel(app.SingleInVersusOut, 'Input Power (dBm)');
            ylabel(app.SingleInVersusOut, 'Output Power (dBm)');
            title(app.SingleInVersusOut, 'Output Power vs. Input Power');

            % Plot Gain on the left y-axis
            yyaxis(app.SingleGain, 'left');
            h1 = plot(app.SingleGain, app.output_PdBm, app.PAGain, 'black');
            hold(app.SingleGain, 'on');

            % Plot the compression points
            if ~isempty(idx_1dB)
                h4 = plot(app.SingleGain, app.output_PdBm(idx_1dB), app.PAGain(idx_1dB), 'blueo', 'MarkerSize', 10);
            end
            if ~isempty(idx_3dB)
                h5 = plot(app.SingleGain, app.output_PdBm(idx_3dB), app.PAGain(idx_3dB), 'redo', 'MarkerSize', 10);
            end
            if ~isempty(saturation_idx)
                h6 = plot(app.SingleGain, saturation_power, app.PAGain(saturation_idx), 'greeno', 'MarkerSize', 10);
            end
            ylabel(app.SingleGain, 'Gain (dB)');

            % Plot DE and PAE on the right y-axis
            yyaxis(app.SingleGain, 'right');
            h2 = plot(app.SingleGain, app.output_PdBm, app.DE, 'blue-');
            h3 = plot(app.SingleGain, app.output_PdBm, app.PAE, 'red--');
            ylabel(app.SingleGain, 'Efficiency (%)');

            hold(app.SingleGain, 'off');
            xlabel(app.SingleGain, 'Output Power (dBm)');
            title(app.SingleGain, 'Gain, DE, and PAE vs. Output Power');

            % Legend order
            legendEntries = {'Gain', 'DE', 'PAE'};
            legendHandles = [h1, h2, h3];
            if exist('h4', 'var')
                legendHandles(end+1) = h4; 
                legendEntries{end+1} = 'Gain_{-1dB}'; 
            end
            if exist('h5', 'var')
                legendHandles(end+1) = h5; 
                legendEntries{end+1} = 'Gain_{-3dB}'; 
            end
            if exist('h6', 'var')
                legendHandles(end+1) = h6; 
                legendEntries{end+1} = 'Saturation'; 
            end
            legend(app.SingleGain, legendHandles, legendEntries, 'Location', 'west');
            
            improveAxesAppearance(app.SingleInVersusOut, 'false');
            improveAxesAppearance(app.SingleGain, 'true');   
        end

        function plotPASweepMeasurement(app)
            % This function plots the data from the sweep measurement type.
            % The first plots shows gain vs. output power. Peak DE, PAE,
            % and gain are also plotted for each frequency. Finally the
            % saturation, and compression points are plotted. If no curve
            % appears for the compression points then there was a frequency
            % with no compression.
            cla(app.SweepGain);
            cla(app.SweepCompressionPoints);
            cla(app.SweepPeakGain);
            cla(app.SweepPeakDEPAE);

            % Get peak values
            peakValues = app.measureRFpeaks();          
            
            % Plot Gain versus output power over the frequency sweep
            hold(app.SweepGain, 'on'); 
            for i = 1:size(app.output_PdBm, 1)
                plot(app.SweepGain, app.output_PdBm(i, :), app.PAGain(i, :));
            end
            hold(app.SweepGain, 'off');
            title(app.SweepGain, 'Gain vs. Output Power');
            xlabel(app.SweepGain, 'Output Power (dBm)');
            ylabel(app.SweepGain, 'Gain (dB)');
            
            % % Plot peak Gain over the frequency sweep
            plot(app.SweepCompressionPoints, app.inputFrequencySweep(1:size(app.output_PdBm, 1))/1E9, peakValues{2}(1:size(app.output_PdBm, 1)), 'blue');
            title(app.SweepCompressionPoints, 'Peak Gain');
            xlabel(app.SweepCompressionPoints, 'Frequency (GHz)');
            ylabel(app.SweepCompressionPoints, 'Gain (dB)');
            
            % Plot peak DE and peak PAE over the frequency sweep
            hold(app.SweepPeakGain, 'on');
            h1 = plot(app.SweepPeakGain, app.inputFrequencySweep(1:size(app.output_PdBm, 1))/1E9, peakValues{3}(1:size(app.output_PdBm, 1)), 'blue');
            h2 = plot(app.SweepPeakGain, app.inputFrequencySweep(1:size(app.output_PdBm, 1))/1E9, peakValues{4}(1:size(app.output_PdBm, 1)), 'red');
            hold(app.SweepPeakGain, 'off');
            title(app.SweepPeakGain, 'Peak DE and PAE');
            xlabel(app.SweepPeakGain, 'Frequency (GHz)');
            ylabel(app.SweepPeakGain, 'Efficiency (%)');
            legend(app.SweepPeakGain, [h1, h2], 'DE', 'PAE', 'Location', 'best');

            % Plot saturation power and compression points over the
            % frequency sweep, if a compression point was not found, 
            % nothing is plotted.
            plotHandles = {};
            plotColors = {'black', 'red', 'blue'};
            xData = {[], [], []};
            yData = {[], [], []};
            legendLabels = {'P_{sat}', 'P_{-1dB}', 'P_{-3dB}'};
            hold(app.SweepPeakDEPAE, 'on'); 

            %Loop through data and collect non-zero points
            for i = 1:size(app.output_PdBm, 1)
                if peakValues{1}(i) ~= 0
                    xData{1} = [xData{1}, app.inputFrequencySweep(i)/1E9];
                    yData{1} = [yData{1}, peakValues{1}(i)];
                end
                if peakValues{5}(i) ~= 0
                    xData{2} = [xData{2}, app.inputFrequencySweep(i)/1E9];
                    yData{2} = [yData{2}, peakValues{5}(i)];
                end
                if peakValues{6}(i) ~= 0
                    xData{3} = [xData{3}, app.inputFrequencySweep(i)/1E9];
                    yData{3} = [yData{3}, peakValues{6}(i)];
                end   
            end
            
            for idx = 1:3
                if ~isempty(xData{idx})
                    plotHandles{end+1} = plot(app.SweepPeakDEPAE, xData{idx}, yData{idx}, [plotColors{idx}, '-']);
                    plot(app.SweepPeakDEPAE, xData{idx}, yData{idx}, plotColors{idx});
                end
            end
            ylabel(app.SweepPeakDEPAE, 'Output Power (dBm)');
            xlabel(app.SweepPeakDEPAE, 'Frequency (GHz)');
            title(app.SweepPeakDEPAE, 'Saturation Power and Compression Points');

            if ~isempty(plotHandles)
                legend(app.SweepPeakDEPAE, [plotHandles{:}], legendLabels(1:length(plotHandles)), 'Location', 'best');
            end
            hold(app.SweepPeakDEPAE, 'off');

            improveAxesAppearance(app.SweepGain, 'YYAxis', 'false', 'LineThickness', 2.5);
            improveAxesAppearance(app.SweepCompressionPoints, 'YYAxis', 'false', 'LineThickness', 2.5);
            improveAxesAppearance(app.SweepPeakGain, 'YYAxis', 'false', 'LineThickness', 2.5);
            improveAxesAppearance(app.SweepPeakDEPAE, 'YYAxis', 'false', 'LineThickness', 2.5);
        end

        function label = formatFreq(~, Frequency)
            % This function takes in a frequency value and returns it in scientific
            % notation for plotting purposes and viewing purposes.
            if Frequency >= 1e9
                label = sprintf('%.2f GHz', Frequency / 1e9);
            elseif Frequency >= 1e6
                label = sprintf('%.2f MHz', Frequency / 1e6);
            elseif Frequency >= 1e3
                label = sprintf('%.2f kHz', Frequency / 1e3);
            else
                label = sprintf('%.2f Hz', Frequency);
            end     
        end

        function adjustedAngle = adjustAngle(~, inputAngle)
            % This function takes in an angle from an input range of [-180, 180] which is the operating range of the turn table
            % and maps the angle to an angle in the range [0, 360], which is the testing range which corresponds to azimuth angles for antenna gain.
            % inputAngle = mod(inputAngle, 360);
            if inputAngle >= 0
                adjustedAngle = inputAngle;
            elseif inputAngle < 0
                adjustedAngle = inputAngle + 360;
            end
        end

        % function run2DMeasurement(app, startFreq, endFreq)
        %     sweepPts = 601;  
        %     tableSpeed = app.TableSpeed.Value;
        %     tableAngles = app.TableStartAngle.Value:app.TableStepAngle.Value:app.TableEndAngle.Value;
        %     arraySize = sweepPts * length(tableAngles);
        % 
        %     app.azimuthAngles = zeros(1, arraySize);
        %     app.sweepFrequenciesqs = zeros(1, arraySize);
        %     app.antennaGain = zeros(1, arraySize);
        % 
        %     idx = 1;
        %     try
        %         writeline(app.EMCenter, sprintf('1A:SPEED %d', tableSpeed));
        %         for azimuthAngle = tableAngles
        %             writeline(app.EMCenter, sprintf('1A:SK %d', azimuthAngle));
        %             status = writeread(app.EMCenter,"1A:*OPC?");
        %             status = str2double(strtrim(status));
        % 
        %             while status ~= 1
        %                 status = writeread(app.EMCenter,"1A:*OPC?");
        %                 status = str2double(strtrim(status));
        %             end
        % 
        %             dataPts = idx:idx+sweepPts-1;
        % 
        %             [app.sParameters, VNAFreqs] = measureSParameters(app.VNA, 2, startFreq, endFreq);
        %             app.azimuthAngles(dataPts) = azimuthAngle;  % Azimuth Angle 
        %             app.sweepFrequencies(dataPts) = VNAFreqs;          % VNA Frequencies
        %             app.sParameters{1}(dataPts) = app.sParameters{1}; % S11
        %             app.sParameters{2}(dataPts) = app.sParameters{2}; % S21
        %             app.antennaGain = measureAntennaGain(app.sweepFrequencies(dataPts), app.sParameters{2}(dataPts), app.setupSpacing);
        % 
        %             idx = idx + sweepPts;
        %         end
        %     catch ME
        %         disp(ME.message);
        %         uialert(app.UIFigure, sprintf('Error while running 2D measurement.'), 'Error', 'Icon', 'warning');
        %     end
        % end
        % 
        % function run3DMeasurement(app)
        %     sweepPts = 601;  
        % 
        %     tableSpeed = app.TableSpeed.Value;
        %     tableAngles = app.TableStartAngle.Value:app.TableStepAngle.Value:app.TableEndAngle.Value;
        % 
        %     % towerSpeed = app.;
        %     % towerAngles = app.:app.:app.;
        % 
        %     %%array allocation
        %     arraySize = sweepPts * length(tableAngles) * length(towerAngles);
        %     app.antennaGain = zeros(1, arraySize);
        %     app.azimuthAngles = zeros(1, arraySize);
        %     app.sweepFrequencies = zeros(1, arraySize);
        % 
        %     idx = 1;
        % 
        %     try 
        %         %set speed of tower and table
        % 
        %         for elevationAngle = towerAngles
        %             %move to elevation angle
        %             for azimuthAngle = tableAngles
        %                 % move to azimuth angle
        %                 % get data same way as 2D measurement, but with phi
        %                 % axis?
        % 
        %                 dataPts = idx:idx+sweepPts-1;
        % 
        %                 [app.sParameters, VNAFreqs] = measureSParameters(app.VNA, 2, startFreq, endFreq);
        %                 app.elevationAngles(dataPts) = elevationAngle;     % Elevation Angle
        %                 app.azimuthAngles(dataPts) = azimuthAngle;         % Azimuth Angle 
        %                 app.sweepFrequencies(dataPts) = VNAFreqs;                 % VNA Frequencies
        %                 app.sParameters{1}(dataPts) = app.sParameters{1};        % S11
        %                 app.sParameters{2}(dataPts) = app.sParameters{2};        % S21
        %                 app.antennaGain = measureAntennaGain(app.sweepFrequencies(dataPts), app.sParameters{2}(dataPts), app.setupSpacing);
        % 
        %             end
        %         end
        %     catch ME
        %         disp(ME.message);
        %         uialert(app.UIFigure, sprintf('Error while running 3D measurement.'), 'Error', 'Icon', 'warning');
        %     end
        % end

        function getBoresightGain(app, startFrequency, endFrequency)
            app.AntennaSizeValueChanged();
            [SParameters, VNAFreqs] = measureSParameters(app.VNA, 2, startFrequency, endFrequency);
            BoresightGain_dBi = measureAntennaGain(VNAFreqs, SParameters{2}, app.setupSpacing);

            combinedData = [double(VNAFreqs)', double(BoresightGain_dBi)', double(SParameters{2})', double(SParameters{3})'];
            combinedNames = {'Frequency (Hz)', 'Boresight Gain (dBi)', 'S21 (dB)', 'Return Loss (dB)'};
            saveData(combinedData, combinedNames);
        end

        function sweepRotationIdenticalAntennas(app, startFrequency, endFrequency)
            app.AntennaSizeValueChanged();
            sweepPts = 201; 
            tableSpeed = app.TableSpeed.Value;

            tableAngles = app.TableStartAngle.Value:app.TableStepAngle.Value:app.TableEndAngle.Value;
            
            arraySize = sweepPts * length(tableAngles);
            AntennaAzimuthAngles = zeros(1, arraySize);
            AntennaTestFrequencies = zeros(1, arraySize);
            AntennaGain_dBi = zeros(1, arraySize);
            S11 = zeros(1, arraySize);
            S21 = zeros(1, arraySize);
            S22 = zeros(1, arraySize);   

            % BoresightFreqs = app.BoresightGainFile.FrequencyHz;
            % BoresightGain = app.BoresightGainFile.BoresightGaindBi;
            % %BoresightS21 = app.BoresightGainFile.S21;
            % %BoresightRL = app.BoresightGainFile.ReturnLossdB;
      
            try
                % Table Speed
                writeline(app.EMCenter, sprintf('1A:SPEED %d', tableSpeed));

                % Sweep for each angle
                for i = 1:length(tableAngles)

                    % Array space for current sweep
                    dataPts = (i-1)*sweepPts + (1:sweepPts);

                    % Adjust azimuth angle to fit testing range [-180,180]
                    adjustedAzimuthAngle = app.adjustAngle(tableAngles(i));

                    % Move to table angle
                    writeline(app.EMCenter, sprintf('1A:SK %d', adjustedAzimuthAngle));
                    status = writeread(app.EMCenter,"1A:*OPC?");
                    status = str2double(strtrim(status));
                    while status ~= 1
                        status = writeread(app.EMCenter,"1A:*OPC?");
                        status = str2double(strtrim(status));
                    end

                    % Get S-Parameters and Frequencies from VNA
                    [SParameters, VNAFrequencies] = measureSParameters(app.VNA, 2, startFrequency, endFrequency); % fix query interrupted error

                    processedGain = measureAntennaGain(VNAFrequencies, SParameters{2}, app.setupSpacing);
                    %- interp1(BoresightFreqs, BoresightGain, VNAFrequencies, "linear", "extrap");

                    % Store results
                    AntennaAzimuthAngles(dataPts) = tableAngles(i);
                    AntennaTestFrequencies(dataPts) = VNAFrequencies;
                    S11(dataPts) = SParameters{1};
                    S21(dataPts) = SParameters{2};
                    S22(dataPts) = SParameters{3};
                    AntennaGain_dBi(dataPts) = processedGain;
                end
            catch ME
                disp(ME.message);
            end

            % app.azimuthAngles = AntennaAzimuthAngles;
            % app.sweepFrequencies = AntennaTestFrequencies;
            % app.sParameters = {AntennaS11, AntennaS21, AntennaS22};
            % app.antennaGain = AntennaGain_dBi;

            combinedData = [double(AntennaAzimuthAngles)', double(AntennaTestFrequencies)', double(AntennaGain_dBi)', double(S21)', double(S22)'];
            combinedNames = {'Azimuth Angles (deg)', 'Frequency (Hz)', 'Antenna Gain (dBi)', 'S21 (dB)', 'Return Loss (dB)'};
            saveData(combinedData, combinedNames);
        end

        function plotAntenna2DMeasurement(app)
            cla(app.GainvsFrequency2D);
            cla(app.ReturnLoss2D);
            cla(app.GainvsAngle2D);
            cla(app.RadiationPlot2D);

            % Specified Angle Plotting  
            angleIndices = (app.angleRange*201+1):((app.angleRange+1)*201);
            angleGain = app.antennaGain(angleIndices);
            angleS11 = app.sParameters{1}(angleIndices);
            filtered_S21 = app.sParameters{2}(angleIndices);

            % Specified Frequency Plotting
            frequencyIndices = app.frequencyRange:201:length(app.antennaGain);
            frequency_Gain = app.antennaGain(frequencyIndices);

            % Antenna Gain vs. Frequency, at specified angle
            plot(app.GainvsFrequency2D, app.sweepFrequencies/1E9, angleGain);
            title(app.GainvsFrequency2D, 'Antenna Gain vs. Frequency');
            xlabel(app.GainvsFrequency2D, 'Frequency (GHz)');
            ylabel(app.GainvsFrequency2D, 'Gain (dBi)');

            % Return Loss (dB) Plot
            plot(app.ReturnLoss2D, app.sweepFrequencies/1E9, angleS11);
            title(app.ReturnLoss2D, 'Return Loss')
            xlabel(app.ReturnLoss2D, 'Frequency (GHz)');
            ylabel(app.ReturnLoss2D, 'RL (dB)');

            % Antenna Gains vs. Angles, at specified frequency.
            plot(app.GainvsAngle2D, app.azimuthAngles, frequency_Gain);
            title(app.GainvsAngle2D, 'Antenna Gain vs. Angle');
            xlabel(app.GainvsAngle2D, 'Angle (degrees)');
            ylabel(app.GainvsAngle2D, 'Gain (dBi)');
            xlim(app.GainvsAngle2D, [0 360]);

            % 2D Radiation Pattern Plot
            polarplot(app.RadiationPlot2D, deg2rad(app.azimuthAngles), frequency_Gain);
            title(app.RadiationPlot2D, sprintf('Radiation Pattern at %.2f GHz', str2double(app.selectedFrequency)));
            rlim(app.RadiationPlot2D, [min(frequency_Gain)-1 max(frequency_Gain)+1]);
            thetalim(app.RadiationPlot2D, [0 360]);

            improveAxesAppearance(app.GainvsFrequency2D, 'LineThickness', 2);
            improveAxesAppearance(app.ReturnLoss2D, 'LineThickness', 2);
            improveAxesAppearance(app.GainvsAngle2D, 'LineThickness', 2);
            %improveAxesAppearance(app.RadiationPlot2D);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Handle Application settings
            app.UIFigure.WindowState = 'maximized';
            drawnow;
            
            % Handle PA testing settings
            app.mType = 'Single';
            app.Span.Enable = 'on';
            app.CenterFrequency.Enable = 'on';
            app.EndFrequency.Enable = 'off';
            app.FrequencyStep.Enable = 'off';

            % Handle Antenna testing settings
            app.RadiationPlot2D = polaraxes(app.GridLayout3);
            app.RadiationPlot2D.Units = "pixels";
            app.RadiationPlot2D.Layout.Row = 2;
            app.RadiationPlot2D.Layout.Column = 2;  
            app.RadiationPlot2D.Title.String = '2D Radiation Pattern';

            app.AntennaCurrentSpacing.Enable = 'off';
            app.LinearSliderCurrentPosition.Enable = 'off';
            app.SpeedPresetDropDown.Enable = 'off';
            app.MovetoPositionButton.Enable = 'off';
            app.LinearSliderNewPosition.Enable = 'off';

        end

        % Button pushed function: PAScanButton
        function PAScanResources(app, event)
            instrumentNames = {'PowerSupply', 'SignalGenerator', 'SpectrumAnalyzer'};
            app.getInstrumentResources(instrumentNames)
        end

        % Button pushed function: PAConnectButton
        function PAConnectInstruments(app, event)
            % Code waits for user to select an address in the dropdown menu.
            % It gets that resource and attempts to connect to it.
            instrumentNames = {'PowerSupply', 'SignalGenerator', 'SpectrumAnalyzer'};
            instrumentResources = {app.PowerSupplyDropDown.Value, app.SignalGeneratorDropDown.Value, app.SpectrumAnalyzerDropDown.Value};
            app.connectInstrument(instrumentNames, instrumentResources);    
        end

        % Value changed function: MeasurementDropDown
        function PAMeasurementType(app, event)
            type = app.MeasurementDropDown.Value;
            app.mType = string(type);
            if strcmp(app.mType, 'Sweep')
                app.Span.Enable = 'off';
                app.CenterFrequency.Enable = 'off';
                app.EndFrequency.Enable = 'on';
                app.FrequencyStep.Enable = 'on';
            elseif strcmp(app.mType, 'Single')
                app.Span.Enable = 'on';
                app.CenterFrequency.Enable = 'on';
                app.EndFrequency.Enable = 'off';
                app.FrequencyStep.Enable = 'off';
            end
        end

        % Button pushed function: StartPAMeasurement
        function PAStartMeasurement(app, event)
            app.runPAMeasurement(app.mType)
        end

        % Button pushed function: PlotPAData
        function PAPlotMeasurement(app, event)
            if strcmp(app.mType, 'Sweep')
                app.plotPASweepMeasurement();
            else
                app.plotPASingleMeasurement();
            end
            %app.PlotPAData.Enable = 'off';
        end

        % Button pushed function: SavePAData
        function PASaveData(app, event)
            if strcmp(app.mType, 'Sweep')
                numFreq = length(app.inputFrequencySweep);
                maxLength = max(length(app.inputPdBmSweep));
                paddedFreq = NaN(maxLength, numFreq);
                for i = 1:numFreq
                    paddedFreq(1,i) = app.inputFrequencySweep(i);
                end
                transpose(paddedFreq);
    
                combinedData = [paddedFreq, app.inputPdBmSweep', app.output_PdBm', app.DCPower', app.PAGain', app.DE', app.PAE'];
                combinedNames = cell(1, size(combinedData, 2));
                parameterNames = {'Frequency (Hz)', 'Output Power (dBm)', 'DC Power (W)', 'Gain (dB)', 'Drain Efficiency (%)', 'Power-Added Efficiency (%)'};
    
                for i = 1:numFreq
                    combinedNames{i} = sprintf('%s [%d]', parameterNames{1}, i);
                end
    
                combinedNames{numFreq + 1} = 'Input Power (dBm)';
    
                index = numFreq + 2;
    
                for k = 2:numel(parameterNames)
                    for j = 1:numFreq
                        combinedNames{index} = sprintf('%s [%d]', parameterNames{k}, j);
                        index = index + 1;
                    end
                end
            else
                maxLength = max(length(app.inputPdBmSingle));
                paddedFreq = nan(maxLength, 1);
                paddedFreq(1:length(app.inputFrequencySingle)) = app.inputFrequencySingle;
                transpose(paddedFreq)
                
                combinedData = [paddedFreq, app.inputPdBmSingle', app.output_PdBm', app.DCPower', app.PAGain', app.DE', app.PAE'];
                combinedNames = {'Frequency (Hz)', 'Input Power (dBm)', 'Output Power (dBm)', 'DC Power (W)', 'Gain (dB)', 'Drain Efficiency (%)', 'Power-Added Efficiency (%)'};
            end

            try
                saveData(combinedData, combinedNames);
            catch ME
                 uialert(app.UIFigure, 'Failed to save data.', 'Error', 'Icon', 'warning');
            end 
        end

        % Button pushed function: LoadPAData
        function PALoadData(app, event)
            try
                disp(app.mType)
                combinedData = loadData('PA');
                if ~isempty(combinedData) 
                    if strcmp(app.mType, 'Sweep')
                        app.inputFrequencySweep = combinedData.FrequencyHz';
                        app.inputPdBmSweep = combinedData.InputPowerdBm';
                        app.output_PdBm = combinedData.OutputPowerdBm';
                        app.DCPower = combinedData.DCPowerW';
                        app.PAGain = combinedData.GaindB';
                        app.DE = combinedData.DrainEfficiency';
                        app.PAE = combinedData.PowerAddedEfficiency';
                    else
                        app.inputFrequencySingle = unique(combinedData.FrequencyHz)';
                        app.inputPdBmSingle = combinedData.InputPowerdBm';
                        app.output_PdBm = combinedData.OutputPowerdBm';
                        app.DCPower = combinedData.DCPowerW';
                        app.PAGain = combinedData.GaindB';
                        app.DE = combinedData.DrainEfficiency';
                        app.PAE = combinedData.PowerAddedEfficiency';
                    end
                end
            catch ME
                uialert(app.UIFigure, 'Failed to load data.', 'Error', 'Icon', 'warning');
            end
        end

        % Button pushed function: AntennaScanButton
        function AntennaScanResources(app, event)
            instrumentNames = {'VNA', 'EMCenter'};
            app.getInstrumentResources(instrumentNames)
        end

        % Button pushed function: AntennaConnectButton
        function AntennaConnectInstruments(app, event)
            % Code waits for user to select an address in the dropdown menu.
            % It gets that resource and attempts to connect to it. 
            instrumentNames = {'VNA', 'EMCenter'};
            instrumentResources = {'TCPIP0::192.168.1.113::hislip0::INSTR', 'TCPIP0::192.168.2.150::inst0::INSTR'};
            %instrumentResources = {app.VNADropDown.Value, app.EMCenterDropDown.Value};
            app.connectInstrument(instrumentNames, instrumentResources);       
        end

        % Button down function: LinearSliderTab
        function LinearSliderConnect(app, event)
            try 
                if isempty(app.LinearSlider)
                    app.LinearSlider = tcpclient('192.168.0.100', 1206);
                    app.LinearSlider.ByteOrder = 'little-endian';
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    app.AntennaCurrentSpacing.Value = 2 + app.offsetSpacing - app.LinearSliderCurrentPosition.Value/100;
                    app.AntennaCurrentSpacing.Enable = 'on';
                    app.LinearSliderCurrentPosition.Enable = 'on';
                    app.SpeedPresetDropDown.Enable = 'on';
                    app.MovetoPositionButton.Enable = 'on';
                    app.LinearSliderNewPosition.Enable = 'on';
                end
            catch ME
                % uialert(app.UIFigure, sprintf('Failed to connect to linear slider. Error: %s', ME), 'Error', 'Icon', 'warning');
                % fprintf('Error: %s\n', ME.message);
                % fprintf('Type of Error: %s\n', ME.identifier);
                % for k = 1:length(ME.stack)
                %     fprintf('In file: %s\nFunction: %s\nLine: %d\n', ...
                %         ME.stack(k).file, ME.stack(k).name, ME.stack(k).line);
                % end

                % Detailed Error Message
                detailedErrorMessage = sprintf('Failed to connect to linear slider.\n\nError: %s\n\nType of Error: %s\n\nStack Trace:\n', ME.message, ME.identifier);
                
                for k = 1:length(ME.stack)
                    detailedErrorMessage = sprintf('%sIn file: %s\nFunction: %s\nLine: %d\n\n', ...
                        detailedErrorMessage, ME.stack(k).file, ME.stack(k).name, ME.stack(k).line);
                end
                
                uialert(app.UIFigure, detailedErrorMessage, 'Error', 'Icon', 'warning');
            end
        end

        % Value changed function: SpeedPresetDropDown
        function LinearSliderSetSpeed(app, event)
             if isempty(app.LinearSlider)
                 try 
                    app.LinearSlider = tcpclient('192.168.0.100', 1206);
                    app.LinearSlider.ByteOrder = 'little-endian';
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    app.AntennaSizeValueChanged();
                catch ME
                    uialert(app.UIFigure, 'Failed to connect to linear slider.', 'Error', 'Icon', 'warning');
                 end
            end

            speed_preset = str2double(app.SpeedPresetDropDown.Value);

            try 
                writeline(app.LinearSlider, sprintf('AXIS1:S%d', speed_preset));
            catch ME
                uialert(app.UIFigure, 'Failed to set linear slider speed.', 'Error', 'Icon', 'warning');
            end
        end

        % Button pushed function: MovetoPositionButton
        function LinearSliderMovePosition(app, event)
            if isempty(app.LinearSlider)
                 try 
                    app.LinearSlider = tcpclient('192.168.0.100', 1206);
                    app.LinearSlider.ByteOrder = 'little-endian';
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    app.AntennaCurrentSpacing.Value = 2 + app.offsetSpacing - app.LinearSliderCurrentPosition.Value/100;
                catch ME
                    uialert(app.UIFigure, 'Failed to connect to linear slider.', 'Error', 'Icon', 'warning');
                 end
            end
            
            try
                writeline(app.LinearSlider, sprintf('AXIS1:SK %d', app.LinearSliderNewPosition.Value));
                motion = writeread(app.LinearSlider, 'AXIS1:DIR?');
                while strcmp(motion, '+1') || strcmp(motion, '-1')
                    motion = writeread(app.LinearSlider, 'AXIS1:DIR?');
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    app.AntennaCurrentSpacing.Value = 2 + app.offsetSpacing - app.LinearSliderCurrentPosition.Value/100;
                    app.setupSpacing = app.AntennaCurrentSpacing.Value;
                end
            catch ME
                uialert(app.UIFigure, 'Failed to move linear slider to new position.', 'Error', 'Icon', 'warning');
            end
        end

        % Button pushed function: StartTestButton
        function AntennaStartMeasurement(app, event)

            startFrequency = app.VNAStartFrequency.Value * 1E6;
            endFrequency = app.VNAEndFrequency.Value * 1E6;
        
            if strcmp(app.VNAMeasurement.Value, 'Gain Characterization')
                app.getBoresightGain(startFrequency, endFrequency);
            elseif strcmp(app.VNAMeasurement.Value, 'Pattern Measurement')
                app.sweepRotationIdenticalAntennas(startFrequency, endFrequency);
            end    
        end

        % Button pushed function: PlotResultsButton
        function AntennaPlotMeasurement(app, event)
            app.plotAntenna2DMeasurement();
        end

        % Button pushed function: SaveTestButton
        function AntennaSaveData(app, event)
            combinedData = [double(app.azimuthAngles)', double(app.sweepFrequencies)', double(app.antennaGain)', double(app.sParameters{1})', double(app.sParameters{2})'];
            combinedNames = {'Table Angle (°)', 'Frequency (Hz)', 'Gain (dBi)', 'S11 (dB)', 'S21 (dB)'};

            try
                saveData(combinedData, combinedNames);
            catch ME
                uialert(app.UIFigure, 'Failed to save data.', 'Error', 'Icon', 'warning');
            end     
        end

        % Button pushed function: LoadResultsButton
        function AntennaLoadData(app, event)
            try
                combinedData = loadData('Antenna');
                if ~isempty(combinedData) 
                    app.azimuthAngles = unique(combinedData.TableAngle)';
                    app.sweepFrequencies = unique(combinedData.FrequencyHz)';
                    app.antennaGain = combinedData.GaindBi';
                    app.sParameters = {combinedData.S11', combinedData.S21'};
                end
            catch ME
                uialert(app.UIFigure, 'Failed to load data.', 'Error', 'Icon', 'warning');
            end
            
            app.PlottingAngleDropDown.Items = string(app.azimuthAngles);
            app.PlottingFrequencyDropDown.Items = string(app.sweepFrequencies/1E9);
            app.selectedAngle = app.PlottingAngleDropDown.Items(1);
            app.selectedFrequency = app.PlottingFrequencyDropDown.Items(1);
            app.angleRange = 0;
            app.frequencyRange = 1;
        end

        % Value changed function: PlottingAngleDropDown
        function AntennaSelectedAngleValue(app, event)
            app.selectedAngle = app.PlottingAngleDropDown.Value;   
            app.angleRange = find(strcmp(app.PlottingAngleDropDown.Items, app.selectedAngle)) - 1;
        end

        % Value changed function: PlottingFrequencyDropDown
        function AntennaSelectedFrequencyValue(app, event)
            app.selectedFrequency = app.PlottingFrequencyDropDown.Value;
            app.frequencyRange = find(strcmp(app.PlottingFrequencyDropDown.Items, app.selectedFrequency));
        end

        % Button pushed function: BrowseFilesButton
        function BrosweBoresightGainFilesButtonPushed(app, event)
            % Stores the contents of the Boresight Gain File into a global
            % property 
            app.BoresightGainFile = loadData('Antenna');

            % Gets the path of the file, to display it to the user
            FilePath = evalin('base', 'loadedFilePath');
            app.BoresightGainFileField.Value = FilePath;
        end

        % Button pushed function: PlotReferenceGainButton
        function PlotBoresightGainFileButtonPushed(app, event)
            cla(app.GainvsFrequencyBoresightGain);
            cla(app.ReturnLossBoresightGain);

            frequencies = app.BoresightGainFile.FrequencyHz;
            gain =  app.BoresightGainFile.BoresightGaindBi;
            %S21 =  app.BoresightGainFile.S21dB;
            ReturnLoss =  app.BoresightGainFile.ReturnLossdB;

            plot(app.GainvsFrequencyBoresightGain, frequencies, gain);
            plot(app.ReturnLossBoresightGain, frequencies, ReturnLoss);
        end

        % Close request function: UIFigure
        function closeupFcn(app, event)
            % This function is executed upon closeup of the app and
            % decides wether to quit or stay on the app. If the user 
            % quits, close all active connections. If the user stays, do 
            % nothing.
            objects_to_delete = {app.PowerSupply, app.SignalGenerator, app.SpectrumAnalyzer, app.VNA, app.EMCenter, app.LinearSlider};
            question = "Close app?";
            choice = uiconfirm(app.UIFigure, question, "Confirm Close");

            switch choice
                case "OK"
                    for i = 1:numel(objects_to_delete)
                        if ~isempty(objects_to_delete{i})
                            delete(objects_to_delete{i});
                        end
                    end
                    delete(app)
                case "Cancel"
            end
        end

        % Value changed function: AntennaSize
        function AntennaSizeValueChanged(app, event)
            antenna_offsets = app.AntennaSize.Value / 1E2;
            app.setupSpacing = 2 + app.offsetSpacing - antenna_offsets - app.LinearSliderCurrentPosition.Value/100;
            app.AntennaCurrentSpacing.Value = app.setupSpacing;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeupFcn, true);
            app.GridLayout19 = uigridlayout(app.UIFigure);
            app.MainTabs = uitabgroup(app.GridLayout19);
            app.PowerAmplifierTab = uitab(app.MainTabs);
            app.PowerAmplifierTab.Title = 'Power Amplifier ';
            app.GridLayout18 = uigridlayout(app.PowerAmplifierTab);
            app.ResultsTabs = uitabgroup(app.GridLayout18);
            app.SingleTab = uitab(app.ResultsTabs);
            app.SingleTab.Title = 'Single';
            app.GridLayout16 = uigridlayout(app.SingleTab);
            app.SingleGain = uiaxes(app.GridLayout16);
            title(app.SingleGain, 'Gain, DE, PAE vs. Output Power')
            xlabel(app.SingleGain, 'Output Power (dBm)')
            zlabel(app.SingleGain, 'Z')
            app.SweepTab = uitab(app.ResultsTabs);
            app.SweepTab.Title = 'Sweep';
            app.GridLayout2 = uigridlayout(app.SweepTab);
            app.SweepGain = uiaxes(app.GridLayout2);
            title(app.SweepGain, 'Gain vs. Output Power')
            xlabel(app.SweepGain, 'Output Power (dBm)')
            ylabel(app.SweepGain, 'Gain (dB)')
            zlabel(app.SweepGain, 'Z')
            app.SweepPeakGain = uiaxes(app.GridLayout2);
            title(app.SweepPeakGain, 'Peak DE and PAE')
            xlabel(app.SweepPeakGain, 'Frequency (GHz)')
            ylabel(app.SweepPeakGain, 'Efficiency (%)')
            zlabel(app.SweepPeakGain, 'Z')
            app.SweepCompressionPoints = uiaxes(app.GridLayout2);
            title(app.SweepCompressionPoints, 'Peak Gain')
            xlabel(app.SweepCompressionPoints, 'Frequency (GHz)')
            ylabel(app.SweepCompressionPoints, 'Gain (dB)')
            zlabel(app.SweepCompressionPoints, 'Z')
            app.SweepPeakDEPAE = uiaxes(app.GridLayout2);
            title(app.SweepPeakDEPAE, 'Saturation Power and Compression Points')
            xlabel(app.SweepPeakDEPAE, 'Frequency (GHz)')
            ylabel(app.SweepPeakDEPAE, 'Output Power (dBm)')
            zlabel(app.SweepPeakDEPAE, 'Z')
            app.MeasurementPanel = uipanel(app.GridLayout18);
            app.MeasurementPanel.Title = 'Measurement';
            app.GridLayout12 = uigridlayout(app.MeasurementPanel);
            app.StartPAMeasurement = uibutton(app.GridLayout12, 'push');
            app.StartPAMeasurement.ButtonPushedFcn = createCallbackFcn(app, @PAStartMeasurement, true);
            app.StartPAMeasurement.Text = 'Start Test';
            app.LoadPAData = uibutton(app.GridLayout12, 'push');
            app.LoadPAData.ButtonPushedFcn = createCallbackFcn(app, @PALoadData, true);
            app.LoadPAData.Text = 'Load Test';
            app.SavePAData = uibutton(app.GridLayout12, 'push');
            app.SavePAData.ButtonPushedFcn = createCallbackFcn(app, @PASaveData, true);
            app.SavePAData.Text = 'Save Test';
            app.PlotPAData = uibutton(app.GridLayout12, 'push');
            app.PlotPAData.ButtonPushedFcn = createCallbackFcn(app, @PAPlotMeasurement, true);
            app.PlotPAData.Text = 'Plot Results';
            app.MeasurementDropDownLabel = uilabel(app.GridLayout12);
            app.MeasurementDropDownLabel.Text = 'Measurement';
            app.MeasurementDropDown = uidropdown(app.GridLayout12);
            app.MeasurementDropDown.Items = {'Single', 'Sweep'};
            app.MeasurementDropDown.ValueChangedFcn = createCallbackFcn(app, @PAMeasurementType, true);
            app.MeasurementDropDown.Value = 'Single';
            app.PASettingsPanel = uitabgroup(app.GridLayout18);
            app.PowerSupplyTab = uitab(app.PASettingsPanel);
            app.PowerSupplyTab.Title = 'Power Supply';
            app.GridLayout11 = uigridlayout(app.PowerSupplyTab);
            app.SupplyVoltageVEditField = uilabel(app.GridLayout11);
            app.SupplyVoltageVEditField.Text = 'DC Voltage Start (V)';
            app.SupplyVoltage = uieditfield(app.GridLayout11, 'numeric');
            app.SupplyVoltageStopVEditField = uilabel(app.GridLayout11);
            app.SupplyVoltageStopVEditField.Text = 'DC Voltage Stop (V)';
            app.SupplyVoltageStop = uieditfield(app.GridLayout11, 'numeric');
            app.SupplyVoltageStepVEditField = uilabel(app.GridLayout11);
            app.SupplyVoltageStepVEditField.Text = 'DC Voltage Step (V)';
            app.SupplyVoltageStep = uieditfield(app.GridLayout11, 'numeric');
            app.ModeDropDownLabel = uilabel(app.GridLayout11);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.GridLayout11);
            app.ModeDropDown.Items = {'Single Supply', 'Dual Supply', 'Quad Supply'};
            app.ModeDropDown.Value = 'Dual Supply';
            app.SupplyDropDownLabel = uilabel(app.GridLayout11);
            app.SupplyDropDownLabel.Text = 'Supply';
            app.SupplyDropDown = uidropdown(app.GridLayout11);
            app.SupplyDropDown.Items = {'1', '2', '3', '4'};
            app.SupplyDropDown.Value = '1';
            app.SignalGeneratorTab = uitab(app.PASettingsPanel);
            app.SignalGeneratorTab.Title = 'Signal Generator';
            app.GridLayout13 = uigridlayout(app.SignalGeneratorTab);
            app.EndFrequencyMHzLabel = uilabel(app.GridLayout13);
            app.EndFrequencyMHzLabel.Text = 'End Frequency (MHz)';
            app.StartFrequencyMHzLabel = uilabel(app.GridLayout13);
            app.StartFrequencyMHzLabel.Text = 'Start Frequency (MHz)';
            app.FrequencyStepMHzLabel = uilabel(app.GridLayout13);
            app.FrequencyStepMHzLabel.Text = 'Frequency Step (MHz)';
            app.StartPowerdBmEditFieldLabel = uilabel(app.GridLayout13);
            app.StartPowerdBmEditFieldLabel.Text = 'Start Power (dBm)';
            app.EndPowerdBmEditFieldLabel = uilabel(app.GridLayout13);
            app.EndPowerdBmEditFieldLabel.Text = 'End Power (dBm)';
            app.PowerStepdBmEditField = uilabel(app.GridLayout13);
            app.PowerStepdBmEditField.Text = 'Power Step (dBm)';
            app.StartFrequency = uieditfield(app.GridLayout13, 'numeric');
            app.EndFrequency = uieditfield(app.GridLayout13, 'numeric');
            app.FrequencyStep = uieditfield(app.GridLayout13, 'numeric');
            app.StartPower = uieditfield(app.GridLayout13, 'numeric');
            app.EndPower = uieditfield(app.GridLayout13, 'numeric');
            app.PowerStep = uieditfield(app.GridLayout13, 'numeric');
            app.SpectrumAnalyzerTab = uitab(app.PASettingsPanel);
            app.SpectrumAnalyzerTab.Title = 'Spectrum Analyzer';
            app.GridLayout14 = uigridlayout(app.SpectrumAnalyzerTab);
            app.ReferenceLeveldBEditField = uilabel(app.GridLayout14);
            app.ReferenceLeveldBEditField.Text = 'Reference Level (dB)';
            app.SweepPointsEditField = uilabel(app.GridLayout14);
            app.SweepPointsEditField.Text = 'Sweep Points';
            app.SpanMHzEditField = uilabel(app.GridLayout14);
            app.SpanMHzEditField.Text = 'Span (MHz)';
            app.ReferenceLevel = uieditfield(app.GridLayout14, 'numeric');
            app.SweepPoints = uieditfield(app.GridLayout14, 'numeric');
            app.Span = uieditfield(app.GridLayout14, 'numeric');
            app.CenterFrequencyMHzEditField = uilabel(app.GridLayout14);
            app.CenterFrequencyMHzEditField.Text = 'Center Frequency (MHz)';
            app.CenterFrequency = uieditfield(app.GridLayout14, 'numeric');
            app.InstrumentConnectionsPanel = uipanel(app.GridLayout18);
            app.InstrumentConnectionsPanel.Title = 'Instrument Connections';
            app.GridLayout15 = uigridlayout(app.InstrumentConnectionsPanel);
            app.PAScanButton = uibutton(app.GridLayout15, 'push');
            app.PAScanButton.ButtonPushedFcn = createCallbackFcn(app, @PAScanResources, true);
            app.PAScanButton.Text = 'Scan';
            app.PAConnectButton = uibutton(app.GridLayout15, 'push');
            app.PAConnectButton.ButtonPushedFcn = createCallbackFcn(app, @PAConnectInstruments, true);
            app.PAConnectButton.Text = 'Connect';
            app.PowerSupplyDropDownLabel = uilabel(app.GridLayout15);
            app.PowerSupplyDropDownLabel.Text = 'Power Supply';
            app.PowerSupplyDropDown = uidropdown(app.GridLayout15);
            app.PowerSupplyDropDown.Items = {'Address'};
            app.PowerSupplyDropDown.Value = 'Address';
            app.SignalGeneratorDropDownLabel = uilabel(app.GridLayout15);
            app.SignalGeneratorDropDownLabel.Text = 'Signal Generator';
            app.SignalGeneratorDropDown = uidropdown(app.GridLayout15);
            app.SignalGeneratorDropDown.Items = {'Address'};
            app.SignalGeneratorDropDown.Value = 'Address';
            app.SpectrumAnalyzerDropDownLabel = uilabel(app.GridLayout15);
            app.SpectrumAnalyzerDropDownLabel.Text = 'Spectrum Analyzer';
            app.SpectrumAnalyzerDropDown = uidropdown(app.GridLayout15);
            app.SpectrumAnalyzerDropDown.Items = {'Address'};
            app.SpectrumAnalyzerDropDown.Value = 'Address';
            app.AntennaTab = uitab(app.MainTabs);
            app.AntennaTab.Title = 'Antenna ';
            app.GridLayout17 = uigridlayout(app.AntennaTab);
            app.AntennaPattern = uitabgroup(app.GridLayout17);
            app.MeasurementAntennaGainTab = uitab(app.AntennaPattern);
            app.MeasurementAntennaGainTab.Title = 'Measurement Antenna Gain';
            app.GridLayout3_2 = uigridlayout(app.MeasurementAntennaGainTab);
            app.ReturnLossBoresightGain = uiaxes(app.GridLayout3_2);
            title(app.ReturnLossBoresightGain, 'Return Loss')
            xlabel(app.ReturnLossBoresightGain, 'Frequency (GHz)')
            ylabel(app.ReturnLossBoresightGain, 'RL (dB)')
            zlabel(app.ReturnLossBoresightGain, 'Z')
            app.GainvsFrequencyBoresightGain = uiaxes(app.GridLayout3_2);
            title(app.GainvsFrequencyBoresightGain, 'Gain vs. Frequency')
            xlabel(app.GainvsFrequencyBoresightGain, 'Frequency (GHz)')
            ylabel(app.GainvsFrequencyBoresightGain, 'Gain (dBi)')
            zlabel(app.GainvsFrequencyBoresightGain, 'Z')
            app.DPatternMeasurementTab = uitab(app.AntennaPattern);
            app.DPatternMeasurementTab.Title = '2D Pattern Measurement';
            app.GridLayout3 = uigridlayout(app.DPatternMeasurementTab);
            app.ReturnLoss2D = uiaxes(app.GridLayout3);
            title(app.ReturnLoss2D, 'Return Loss')
            xlabel(app.ReturnLoss2D, 'Frequency (GHz)')
            ylabel(app.ReturnLoss2D, 'RL (dB)')
            zlabel(app.ReturnLoss2D, 'Z')
            app.GainvsFrequency2D = uiaxes(app.GridLayout3);
            title(app.GainvsFrequency2D, 'Gain vs. Frequency')
            xlabel(app.GainvsFrequency2D, 'Frequency (GHz)')
            ylabel(app.GainvsFrequency2D, 'Gain (dBi)')
            zlabel(app.GainvsFrequency2D, 'Z')
            app.GainvsAngle2D = uiaxes(app.GridLayout3);
            title(app.GainvsAngle2D, 'Antenna Gain vs. Angle')
            xlabel(app.GainvsAngle2D, 'Angle (°)')
            ylabel(app.GainvsAngle2D, 'Gain (dBi)')
            zlabel(app.GainvsAngle2D, 'Z')
            app.PlotSettingsPanel = uipanel(app.GridLayout17);
            app.PlotSettingsPanel.Title = 'Plot Settings';
            app.GridLayout4 = uigridlayout(app.PlotSettingsPanel);
            app.PlottingFrequencyDropDownLabel = uilabel(app.GridLayout4);
            app.PlottingFrequencyDropDownLabel.Text = 'Plotting Frequency';
            app.PlottingFrequencyDropDown = uidropdown(app.GridLayout4);
            app.PlottingFrequencyDropDown.Items = {'(GHz)'};
            app.PlottingFrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaSelectedFrequencyValue, true);
            app.PlottingFrequencyDropDown.Value = '(GHz)';
            app.PlottingAngleDropDownLabel = uilabel(app.GridLayout4);
            app.PlottingAngleDropDownLabel.Text = 'Plotting Angle';
            app.PlottingAngleDropDown = uidropdown(app.GridLayout4);
            app.PlottingAngleDropDown.Items = {'(°)'};
            app.PlottingAngleDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaSelectedAngleValue, true);
            app.PlottingAngleDropDown.Value = '(°)';
            app.TestSettingsPanel = uipanel(app.GridLayout17);
            app.TestSettingsPanel.Title = 'Test Settings';
            app.GridLayout5 = uigridlayout(app.TestSettingsPanel);
            app.SaveTestButton = uibutton(app.GridLayout5, 'push');
            app.SaveTestButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaSaveData, true);
            app.SaveTestButton.Text = 'Save Test';
            app.StartTestButton = uibutton(app.GridLayout5, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaStartMeasurement, true);
            app.StartTestButton.Text = 'Start Test';
            app.LoadResultsButton = uibutton(app.GridLayout5, 'push');
            app.LoadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaLoadData, true);
            app.LoadResultsButton.Text = 'Load Results';
            app.PlotResultsButton = uibutton(app.GridLayout5, 'push');
            app.PlotResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaPlotMeasurement, true);
            app.PlotResultsButton.Text = 'Plot Results';
            app.AntennaSettings = uitabgroup(app.GridLayout17);
            app.VNATab = uitab(app.AntennaSettings);
            app.VNATab.Title = 'VNA';
            app.GridLayout21 = uigridlayout(app.VNATab);
            app.StartFrequectSpanMHzLabel_15 = uilabel(app.GridLayout21);
            app.StartFrequectSpanMHzLabel_15.Text = 'Start Frequency (MHz)';
            app.VNAStartFrequency = uieditfield(app.GridLayout21, 'numeric');
            app.EndFrequencyMHzLabel_6 = uilabel(app.GridLayout21);
            app.EndFrequencyMHzLabel_6.Text = 'End Frequency (MHz)';
            app.VNAEndFrequency = uieditfield(app.GridLayout21, 'numeric');
            app.MeasurementDropDown_2Label = uilabel(app.GridLayout21);
            app.MeasurementDropDown_2Label.Text = 'Measurement';
            app.VNAMeasurement = uidropdown(app.GridLayout21);
            app.VNAMeasurement.Items = {'Gain Characterization', 'Pattern Measurement'};
            app.VNAMeasurement.Value = 'Gain Characterization';
            app.BrowseFilesButton = uibutton(app.GridLayout21, 'push');
            app.BrowseFilesButton.ButtonPushedFcn = createCallbackFcn(app, @BrosweBoresightGainFilesButtonPushed, true);
            app.BrowseFilesButton.Text = 'Browse Files';
            app.PlotReferenceGainButton = uibutton(app.GridLayout21, 'push');
            app.PlotReferenceGainButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBoresightGainFileButtonPushed, true);
            app.PlotReferenceGainButton.Text = 'Plot Reference Gain';
            app.BoresightGainFileField = uieditfield(app.GridLayout21, 'text');
            app.Table = uitab(app.AntennaSettings);
            app.Table.Title = 'Table';
            app.GridLayout8 = uigridlayout(app.Table);
            app.StartFrequectSpanMHzLabel_11 = uilabel(app.GridLayout8);
            app.StartFrequectSpanMHzLabel_11.Text = 'Start Angle (°)';
            app.TableStartAngle = uieditfield(app.GridLayout8, 'numeric');
            app.TableStartAngle.Limits = [-180 180];
            app.StartFrequectSpanMHzLabel_12 = uilabel(app.GridLayout8);
            app.StartFrequectSpanMHzLabel_12.Text = 'Step Size (°)';
            app.TableStepAngle = uieditfield(app.GridLayout8, 'numeric');
            app.StartFrequectSpanMHzLabel_13 = uilabel(app.GridLayout8);
            app.StartFrequectSpanMHzLabel_13.Text = 'End Angle (°)';
            app.TableEndAngle = uieditfield(app.GridLayout8, 'numeric');
            app.TableEndAngle.Limits = [-180 180];
            app.StartFrequectSpanMHzLabel_14 = uilabel(app.GridLayout8);
            app.StartFrequectSpanMHzLabel_14.Text = 'Table Speed';
            app.TableSpeed = uieditfield(app.GridLayout8, 'numeric');
            app.TableSpeed.Limits = [0 100];
            app.TowerTab = uitab(app.AntennaSettings);
            app.TowerTab.Title = 'Tower';
            app.GridLayout9 = uigridlayout(app.TowerTab);
            app.StartFrequectSpanMHzLabel_16 = uilabel(app.GridLayout9);
            app.StartFrequectSpanMHzLabel_16.Text = 'Start Angle (°)';
            app.TowerStartAngle = uieditfield(app.GridLayout9, 'numeric');
            app.TowerStartAngle.Limits = [0 360];
            app.StartFrequectSpanMHzLabel_17 = uilabel(app.GridLayout9);
            app.StartFrequectSpanMHzLabel_17.Text = 'Step Size (°)';
            app.TowerStepAngle = uieditfield(app.GridLayout9, 'numeric');
            app.StartFrequectSpanMHzLabel_18 = uilabel(app.GridLayout9);
            app.StartFrequectSpanMHzLabel_18.Text = 'End Angle (°)';
            app.TowerEndAngle = uieditfield(app.GridLayout9, 'numeric');
            app.TowerEndAngle.Limits = [0 360];
            app.StartFrequectSpanMHzLabel_19 = uilabel(app.GridLayout9);
            app.StartFrequectSpanMHzLabel_19.Text = 'Tower Speed';
            app.TowerSpeed = uieditfield(app.GridLayout9, 'numeric');
            app.TowerSpeed.Limits = [0 100];
            app.LinearSliderTab = uitab(app.AntennaSettings);
            app.LinearSliderTab.Title = 'Linear Slider';
            app.LinearSliderTab.ButtonDownFcn = createCallbackFcn(app, @LinearSliderConnect, true);
            app.GridLayout10 = uigridlayout(app.LinearSliderTab);
            app.MovetoPositionButton = uibutton(app.GridLayout10, 'push');
            app.MovetoPositionButton.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderMovePosition, true);
            app.MovetoPositionButton.Text = 'Move to Position';
            app.LinearSliderNewPosition = uieditfield(app.GridLayout10, 'numeric');
            app.LinearSliderNewPosition.Limits = [0 200];
            app.LinearSliderNewPosition.Placeholder = 'cm';
            app.CurrentPositioncmEditFieldLabel = uilabel(app.GridLayout10);
            app.CurrentPositioncmEditFieldLabel.Text = 'Current Position (cm)';
            app.LinearSliderCurrentPosition = uieditfield(app.GridLayout10, 'numeric');
            app.LinearSliderCurrentPosition.Limits = [0 200];
            app.LinearSliderCurrentPosition.Editable = 'off';
            app.CurrentSpacingmEditFieldLabel = uilabel(app.GridLayout10);
            app.CurrentSpacingmEditFieldLabel.Text = 'Current Spacing (m)';
            app.AntennaCurrentSpacing = uieditfield(app.GridLayout10, 'numeric');
            app.AntennaCurrentSpacing.Limits = [0 Inf];
            app.AntennaCurrentSpacing.Editable = 'off';
            app.SpeedPresetDropDownLabel = uilabel(app.GridLayout10);
            app.SpeedPresetDropDownLabel.Text = 'Speed Preset';
            app.SpeedPresetDropDown = uidropdown(app.GridLayout10);
            app.SpeedPresetDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.SpeedPresetDropDown.ValueChangedFcn = createCallbackFcn(app, @LinearSliderSetSpeed, true);
            app.SpeedPresetDropDown.Value = '1';
            app.AntennaOffsetcmEditFieldLabel = uilabel(app.GridLayout10);
            app.AntennaOffsetcmEditFieldLabel.Text = 'Antenna Offset (cm)';
            app.AntennaSize = uieditfield(app.GridLayout10, 'numeric');
            app.AntennaSize.ValueChangedFcn = createCallbackFcn(app, @AntennaSizeValueChanged, true);
            app.InstrumentConnectionsPanel_2 = uipanel(app.GridLayout17);
            app.InstrumentConnectionsPanel_2.Title = 'Instrument Connections';
            app.GridLayout6 = uigridlayout(app.InstrumentConnectionsPanel_2);
            app.AntennaScanButton = uibutton(app.GridLayout6, 'push');
            app.AntennaScanButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaScanResources, true);
            app.AntennaScanButton.Text = 'Scan';
            app.AntennaConnectButton = uibutton(app.GridLayout6, 'push');
            app.AntennaConnectButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaConnectInstruments, true);
            app.AntennaConnectButton.Text = 'Connect';
            app.VNADropDownLabel = uilabel(app.GridLayout6);
            app.VNADropDownLabel.Text = 'VNA';
            app.VNADropDown = uidropdown(app.GridLayout6);
            app.VNADropDown.Items = {'Address'};
            app.VNADropDown.Value = 'Address';
            app.EMCenterDropDownLabel = uilabel(app.GridLayout6);
            app.EMCenterDropDownLabel.Text = 'EM Center';
            app.EMCenterDropDown = uidropdown(app.GridLayout6);
            app.EMCenterDropDown.Items = {'Address'};
            app.EMCenterDropDown.Value = 'Address';
            app.InfoTab = uitab(app.MainTabs);
            app.InfoTab.Title = 'Info';
            app.GridLayout20 = uigridlayout(app.InfoTab);
            app.Image = uiimage(app.GridLayout20);
            app.Image.ImageSource = 'ARES logo.jpg';
            app.Label = uilabel(app.GridLayout20);
            app.Label.Text = 'Automated Radio Evaluation Suite';
            app.Label_2 = uilabel(app.GridLayout20);
            app.Label_2.Text = '';
            app.V01BETARelaseLabel = uilabel(app.GridLayout20);
            app.V01BETARelaseLabel.Text = 'V0.1 BETA Relase';
            app.Hyperlink = uihyperlink(app.GridLayout20);
            app.Hyperlink.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite';
            app.Hyperlink.Text = 'GitHub Repo';
            app.AuthorJoseAbrahanBolaosVargasMentorAlexDSantiagoVargasLabel = uilabel(app.GridLayout20);
            app.AuthorJoseAbrahanBolaosVargasMentorAlexDSantiagoVargasLabel.Text = {'Author: Jose Abrahan Bolaños Vargas'; 'Mentor: Alex D. Santiago Vargas'; 'PI: Dimitrios Peroulis'; 'Adaptive Radio Electronics and Sensors (ARES) Group'};
            app.Hyperlink2 = uihyperlink(app.GridLayout20);
            app.Hyperlink2.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite?tab=readme-ov-file#pa-measurement-tutorial';
            app.Hyperlink2.Text = 'PA Measurement Tutorial';
            app.Hyperlink2_2 = uihyperlink(app.GridLayout20);
            app.Hyperlink2_2.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite?tab=readme-ov-file#antenna-measurement-tutorial';
            app.Hyperlink2_2.Text = 'Antenna Tutorial';
            app.Hyperlink2_3 = uihyperlink(app.GridLayout20);
            app.Hyperlink2_3.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite/blob/main/README.md';
            app.Hyperlink2_3.Text = 'README';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end